classdef transpGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        TRANSPguiUIFigure        matlab.ui.Figure
        ReadTRANSPCDFfileButton  matlab.ui.control.Button
        NUBEAMButton             matlab.ui.control.Button
        TimeEditFieldLabel       matlab.ui.control.Label
        TimeEditField            matlab.ui.control.NumericEditField
        NofileopenedLabel        matlab.ui.control.Label
        TabGroup                 matlab.ui.container.TabGroup
        DTab                     matlab.ui.container.Tab
        vars1DListBox            matlab.ui.control.ListBox
        DTab_2                   matlab.ui.container.Tab
        vars2DListBox            matlab.ui.control.ListBox
        timeplot                 matlab.ui.control.UIAxes
        rhoplot                  matlab.ui.control.UIAxes
        ExtracteqdskButton       matlab.ui.control.Button
        runcheaseCheckBox        matlab.ui.control.CheckBox
        TensionEditFieldLabel    matlab.ui.control.Label
        TensionEditField         matlab.ui.control.NumericEditField
        NeutralsButton           matlab.ui.control.Button
        TimeLabel                matlab.ui.control.Label
        ExportNUBEAMButton       matlab.ui.control.Button
    end


    properties (Access = private)
        time % time
        transpmat % transp matlab structure
        nubeammat
    end
    
    properties (SetObservable)
        fname % name of transp file
        vars_1d % name of 1D vars
        vars_1d_desc % description of 1D vars
        vars_2d % name of 2D vars
        vars_2d_desc % Description of 2D vars 
    end
    
    methods (Access = private)
    
        function splitvars(app, mat_struct)
            app.vars_1d = {};
            app.vars_1d_desc = {};
            app.vars_2d = {};
            app.vars_2d_desc = {};            
            n1d=1;
            n2d=1;
            fields=fieldnames(mat_struct.allvars);
            for i=1:length(fields)
                dim=mat_struct.allvars.(fields{i}).Dimensions;
                desc=mat_struct.allvars.(fields{i}).long_name;
                tmp=size(dim);
                if tmp(2) == 1
                    app.vars_1d(n1d) = fields(i);
                    app.vars_1d_desc(n1d) = {desc};
                    n1d=n1d+1;
                elseif tmp(2) == 2
                    app.vars_2d(n2d) = fields(i);
                    app.vars_2d_desc(n2d) = {desc};
                    n2d=n2d+1;
                end
            end
            app.vars1DListBox.ItemsData = app.vars_1d;
            app.vars1DListBox.Items = app.vars_1d_desc;       
            app.vars2DListBox.ItemsData = app.vars_2d;
            app.vars2DListBox.Items = app.vars_2d_desc;  
        end

    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
        end

        % Button pushed function: ExtracteqdskButton
        function ExtracteqdskButtonPushed(app, event)
            [funnetcdf,fname_out, globalsvalues, namelist_struct]=forCHEASE_mv(app.fname,...
                app.transpmat, app.time,app.runcheaseCheckBox.Value, ...
                app.TensionEditField.Value);
            assignin('base', 'funnetcdf', funnetcdf);
            assignin('base', 'fname_out', fname_out);    
            assignin('base', 'globalsvalues', globalsvalues);    
            assignin('base', 'namelist_struct', namelist_struct);            
        end

        % Button pushed function: NeutralsButton
        function NeutralsButtonPushed(app, event)
            n0_plot(app.transpmat, app.time)
        end

        % Value changed function: TimeEditField
        function TimeEditFieldValueChanged(app, event)
            app.time = app.TimeEditField.Value;
            assignin('base', 'time', app.time);
        end

        % Button pushed function: ReadTRANSPCDFfileButton
        function ReadTRANSPCDFfileButtonPushed(app, event)
            [app.fname,pname]=uigetfile('*.cdf','Open NetCDF File');
            app.transpmat = cdf2mat([pname ,app.fname]);
            assignin('base', 'fname', app.fname);
            assignin('base', 'transpmat', app.transpmat);
            splitvars(app, app.transpmat)
            app.NofileopenedLabel.Text = ['File opened: ',app.fname];
            hold(app.timeplot, 'on')
            plot(app.timeplot, app.transpmat.coords.TIME.data, app.transpmat.allvars.PCUR.data*1e-3, 'k', 'DisplayName', 'Ip')
            plot(app.timeplot, app.transpmat.coords.TIME.data, app.transpmat.allvars.PINJ.data*1e-3*0.5, 'r', 'DisplayName', 'beam')
            hold(app.timeplot, 'off')
            app.TimeLabel.Text = ['[',num2str(min(app.transpmat.coords.TIME.data)),' - ',num2str(max(app.transpmat.coords.TIME.data)),']'];
        end

        % Button pushed function: NUBEAMButton
        function NUBEAMButtonPushed(app, event)
            app.nubeammat = get_nubeam(app.transpmat, app.time);
            nubeam_plot(app.nubeammat)
        end

        % Value changed function: vars1DListBox
        function plot_1dvar(app, event)
            value = app.vars1DListBox.Value;
            plot(app.timeplot,app.transpmat.allvars.TIME.data, app.transpmat.allvars.(value).data)
            title(app.timeplot, value)
        end

        % Value changed function: vars2DListBox
        function plot_2dvar(app, event)
            value = app.vars2DListBox.Value;
            [~,ind] = min(abs(app.transpmat.allvars.TIME.data-app.time));
            plot(app.rhoplot,app.transpmat.allvars.X.data(:,ind), app.transpmat.allvars.(value).data(:,ind))
            title(app.rhoplot, value)
        end

        % Callback function
        function vars1DListBoxValueChanged(app, event)
            app.vars1DListBox.Items = app.vars_1d;
            %app.vars1DListBox.ItemsData = app.vars_1d_desc;            
        end

        % Button pushed function: ExportNUBEAMButton
        function ExportNUBEAMButtonPushed(app, event)
            write_nubeam(app.nubeammat)
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.TRANSPguiUIFigure = uifigure;
            app.TRANSPguiUIFigure.Name = 'TRANSPgui';
            app.ReadTRANSPCDFfileButton = uibutton(app.TRANSPguiUIFigure, 'push');
            app.ReadTRANSPCDFfileButton.ButtonPushedFcn = createCallbackFcn(app, @ReadTRANSPCDFfileButtonPushed, true);
            app.ReadTRANSPCDFfileButton.Text = 'Read TRANSP CDF file';
            app.NUBEAMButton = uibutton(app.TRANSPguiUIFigure, 'push');
            app.NUBEAMButton.ButtonPushedFcn = createCallbackFcn(app, @NUBEAMButtonPushed, true);
            app.NUBEAMButton.Text = 'NUBEAM';
            app.TimeEditFieldLabel = uilabel(app.TRANSPguiUIFigure);
            app.TimeEditFieldLabel.Text = 'Time';
            app.TimeEditField = uieditfield(app.TRANSPguiUIFigure, 'numeric');
            app.TimeEditField.ValueChangedFcn = createCallbackFcn(app, @TimeEditFieldValueChanged, true);
            app.NofileopenedLabel = uilabel(app.TRANSPguiUIFigure);
            app.NofileopenedLabel.Text = 'No file opened';
            app.TabGroup = uitabgroup(app.TRANSPguiUIFigure);
            app.DTab = uitab(app.TabGroup);
            app.DTab.Title = '1D';
            app.vars1DListBox = uilistbox(app.DTab);
            app.vars1DListBox.Items = {};
            app.vars1DListBox.ValueChangedFcn = createCallbackFcn(app, @plot_1dvar, true);
            app.vars1DListBox.Value = {};
            app.DTab_2 = uitab(app.TabGroup);
            app.DTab_2.Title = '2D';
            app.vars2DListBox = uilistbox(app.DTab_2);
            app.vars2DListBox.Items = {};
            app.vars2DListBox.ValueChangedFcn = createCallbackFcn(app, @plot_2dvar, true);
            app.vars2DListBox.Value = {};
            app.timeplot = uiaxes(app.TRANSPguiUIFigure);
            xlabel(app.timeplot, 't (s)')
            app.rhoplot = uiaxes(app.TRANSPguiUIFigure);
            xlabel(app.rhoplot, 'rho')
            app.ExtracteqdskButton = uibutton(app.TRANSPguiUIFigure, 'push');
            app.ExtracteqdskButton.ButtonPushedFcn = createCallbackFcn(app, @ExtracteqdskButtonPushed, true);
            app.ExtracteqdskButton.Text = 'Extract eqdsk';
            app.runcheaseCheckBox = uicheckbox(app.TRANSPguiUIFigure);
            app.runcheaseCheckBox.Text = 'run chease';
            app.TensionEditFieldLabel = uilabel(app.TRANSPguiUIFigure);
            app.TensionEditFieldLabel.Text = 'Tension';
            app.TensionEditField = uieditfield(app.TRANSPguiUIFigure, 'numeric');
            app.TensionEditField.Value = 0.0001;
            app.NeutralsButton = uibutton(app.TRANSPguiUIFigure, 'push');
            app.NeutralsButton.ButtonPushedFcn = createCallbackFcn(app, @NeutralsButtonPushed, true);
            app.NeutralsButton.Text = 'Neutrals';
            app.TimeLabel = uilabel(app.TRANSPguiUIFigure);
            app.TimeLabel.Text = '';
            app.ExportNUBEAMButton = uibutton(app.TRANSPguiUIFigure, 'push');
            app.ExportNUBEAMButton.ButtonPushedFcn = createCallbackFcn(app, @ExportNUBEAMButtonPushed, true);
            app.ExportNUBEAMButton.Text = 'Export NUBEAM';
        end
    end

    methods (Access = public)

        % Construct app
        function app = transpGUI

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.TRANSPguiUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.TRANSPguiUIFigure)
        end
    end
end